classdef LocalFinding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AxesOverview              matlab.ui.control.UIAxes
        AxesDetail                matlab.ui.control.UIAxes
        ControllerPanel           matlab.ui.container.Panel
        SelectFloorDropDownLabel  matlab.ui.control.Label
        SelectFloorDropDown       matlab.ui.control.DropDown
        Panel                     matlab.ui.container.Panel
        CheckButton               matlab.ui.control.Button
        RoomEditFieldLabel        matlab.ui.control.Label
        RoomEditField             matlab.ui.control.EditField
        FloorEditFieldLabel       matlab.ui.control.Label
        FloorEditField            matlab.ui.control.EditField
        XLabel                    matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        lblClockLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        patchs
        presenter Presenter
        updating_timer % Description
        listOfObjectView %Store list of scatter3 objects
    end
    
    methods (Access = public)
        %Plot Model
        function prepareDraw(app, axesIndex)
            switch axesIndex
                case 1 
                    axes = app.AxesOverview;
                case 2 
                    axes = app.AxesDetail;
            end
                    
            hold(axes, 'on');
        end
    
        function finishDraw(app, axesIndex)
            switch axesIndex
                case 1 
                    axes = app.AxesOverview;
                case 2 
                    axes = app.AxesDetail;
            end
            
            light(axes);
            daspect(axes, [1 1 1])
            view(axes, 3)
            xlabel(axes,'X'),ylabel(axes, 'Y'),zlabel(axes,'Z')
            rotate3d(axes, 'on');
            hold(axes, 'off');
        end
        function plotModel(app, modelInformation, axesIndex)
            if axesIndex == 1 
                axes = app.AxesOverview;
            elseif axesIndex == 2 
                axes = app.AxesDetail;
            end
            faces = modelInformation.faces;
            vertexes = modelInformation.vertexes;
            colors = modelInformation.colors;
            app.patchs = patch(axes, 'faces', faces, 'vertices', vertexes);
            set(app.patchs, 'facec', 'flat');
            set(app.patchs, 'FaceVertexCData', colors);
            set(app.patchs, 'EdgeColor', [0.2980 0.6863 0.6667]);
        end
    
        %Draw list of objects
        function object = drawObject(app, object, axesIndex)
            location = object.location;
            name = object.name;
            %------------------------------------------------------------------------------------------------------------------
            %------------------------------------------------------------------------------------------------------------------
            switch axesIndex
                case 1
                    object.viewInformation.point = scatter3(app.AxesOverview, location.x, location.y, location.z, 'filled', 'blue');
                    object.viewInformation.text = text(app.AxesOverview, location.x, location.y, location.z, name);
                case 2
                    object.viewDetailInformation.point = scatter3(app.AxesDetail, location.x, location.y, location.z, 'filled', 'blue');
                    object.viewDetailInformation.text = text(app.AxesDetail, location.x, location.y, location.z, name);
            end
        end
    
        function updateObject(app,  object, axesIndex)
            location = object.location;
            switch axesIndex
                case 1
                    if isempty(object.viewInformation)
                        return;
                    end
                    set(object.viewInformation.point, 'XData', location.x, 'YData', location.y, 'ZData', location.z);
                    set(object.viewInformation.text, 'Position', [location.x location.y location.z]);
                case 2
                    if isempty(object.viewDetailInformation)
                        return;
                    end
                    set(object.viewDetailInformation.point, 'XData', location.x, 'YData', location.y, 'ZData', location.z);
                    set(object.viewDetailInformation.text, 'Position', [location.x location.y location.z]);
            end
        end
    end
    
    methods (Access = private) 
        function buildModel(app) 
            app.presenter.buildModel(app);
        end
        
        function timer_tick(object, event, app)
            str = datestr(now, 'HH:MM:SS');             
            object.lblClockLabel.Text = str;
            object.presenter.updateObjects(object);
        end
    
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.presenter = Presenter();
            
            app.updating_timer=timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', {@app.timer_tick});
            start(app.updating_timer);
            
            app.buildModel()
            set(app.SelectFloorDropDown, 'Items', app.presenter.getListOfFloors());
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            location.x = app.XEditField.Value;
            location.y = app.YEditField.Value;
            location.z = app.ZEditField.Value;
            
            
            hold(app.AxesOverview, 'on');
            scatter3(app.AxesOverview, location.x, location.y, location.z, 'filled', 'red');
            result = app.presenter.checkArea(location);
            try
                app.RoomEditField.Value = char(result.label);
                app.FloorEditField.Value = sprintf('%.f', result.floor);
            catch
                app.RoomEditField.Value = 'Out of model';
                app.FloorEditField.Value = '0';
            end
            
        end

        % Value changed function: SelectFloorDropDown
        function SelectFloorDropDownValueChanged(app, event)
            value = app.SelectFloorDropDown.Value;
            try
                value = str2double(value);
            catch
                value = 1;
            end
            cla(app.AxesDetail);
            app.presenter.viewOnFloor(value, app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AxesOverview = uiaxes(app.UIFigure);
            title(app.AxesOverview, 'Model')
            xlabel(app.AxesOverview, 'X')
            ylabel(app.AxesOverview, 'Y')
            app.AxesDetail = uiaxes(app.UIFigure);
            title(app.AxesDetail, 'Title')
            xlabel(app.AxesDetail, 'X')
            ylabel(app.AxesDetail, 'Y')
            app.ControllerPanel = uipanel(app.UIFigure);
            app.ControllerPanel.Title = 'Controller';
            app.SelectFloorDropDownLabel = uilabel(app.ControllerPanel);
            app.SelectFloorDropDownLabel.Text = 'Select Floor';
            app.SelectFloorDropDown = uidropdown(app.ControllerPanel);
            app.SelectFloorDropDown.Items = {'1'};
            app.SelectFloorDropDown.Editable = 'on';
            app.SelectFloorDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFloorDropDownValueChanged, true);
            app.SelectFloorDropDown.Value = '1';
            app.Panel = uipanel(app.ControllerPanel);
            app.CheckButton = uibutton(app.Panel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.RoomEditFieldLabel = uilabel(app.Panel);
            app.RoomEditFieldLabel.Text = 'Room';
            app.RoomEditField = uieditfield(app.Panel, 'text');
            app.FloorEditFieldLabel = uilabel(app.Panel);
            app.FloorEditFieldLabel.Text = 'Floor';
            app.FloorEditField = uieditfield(app.Panel, 'text');
            app.XLabel = uilabel(app.Panel);
            app.XLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Panel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.Panel, 'numeric');
            app.lblClockLabel = uilabel(app.ControllerPanel);
            app.lblClockLabel.Text = 'lblClock';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LocalFinding

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end